classdef HydroScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MotorsTab                     matlab.ui.container.Tab
        DropDown_MovementType         matlab.ui.control.DropDown
        EditField_tav                 matlab.ui.control.EditField
        EditField_trv                 matlab.ui.control.EditField
        TButton_Minus                 matlab.ui.control.Button
        TButton_Plus                  matlab.ui.control.Button
        Lamp_Motor                    matlab.ui.control.Lamp
        ConnectButton_Motor           matlab.ui.control.StateButton
        GohomeButton                  matlab.ui.control.Button
        EditField_zav                 matlab.ui.control.EditField
        EditField_yav                 matlab.ui.control.EditField
        EditField_xav                 matlab.ui.control.EditField
        EditField_zrv                 matlab.ui.control.EditField
        EditField_yrv                 matlab.ui.control.EditField
        EditField_xrv                 matlab.ui.control.EditField
        SethomeButton                 matlab.ui.control.Button
        ZButton_Minus                 matlab.ui.control.Button
        ZButton_Plus                  matlab.ui.control.Button
        YButton_Minus                 matlab.ui.control.Button
        YButton_Plus                  matlab.ui.control.Button
        XButton_Minus                 matlab.ui.control.Button
        XButton_Plus                  matlab.ui.control.Button
        PicoTab                       matlab.ui.container.Tab
        PulseAcqTimeEditField         matlab.ui.control.EditField
        AcqtimeusLabel                matlab.ui.control.Label
        PulseRepeatkHzEditField       matlab.ui.control.EditField
        RepeatkHzEditFieldLabel       matlab.ui.control.Label
        SliderYaxis                   matlab.ui.control.Slider
        SliderAverages                matlab.ui.control.Slider
        Lamp_Pico                     matlab.ui.control.Lamp
        ConnectButton_Pico            matlab.ui.control.StateButton
        PulseButton                   matlab.ui.control.StateButton
        PulsePanel                    matlab.ui.container.Panel
        PulseTypeDropDown             matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        PulseAmplitudeVEditField      matlab.ui.control.EditField
        AmplitudeVEditFieldLabel      matlab.ui.control.Label
        PulseNumberofcyclesEditField  matlab.ui.control.EditField
        NumberofcyclesEditFieldLabel  matlab.ui.control.Label
        PulseCarrierFrequencyMHzEditField  matlab.ui.control.EditField
        CarrierFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        UIAxes_Pico                   matlab.ui.control.UIAxes
        ScanTab                       matlab.ui.container.Tab
        widthusLabel                  matlab.ui.control.Label
        TimegatestartusLabel          matlab.ui.control.Label
        EditField_timegate_width      matlab.ui.control.EditField
        EditField_timegate_start      matlab.ui.control.EditField
        ScanButton                    matlab.ui.control.StateButton
        EditField_rotation_step       matlab.ui.control.EditField
        EditField_line_step           matlab.ui.control.EditField
        EditField_rotation_range      matlab.ui.control.EditField
        RotateCheckBox                matlab.ui.control.CheckBox
        EditField_line_range          matlab.ui.control.EditField
        LineDropDown_axis_selection   matlab.ui.control.DropDown
        UIAxesPressure                matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Motor % MotorXYZ class
        Pico % PicoScope class
        Acou % Acoustics class
        Pressure
        PressureIndex
    end

    methods (Access = private)

        function [p x] = LineScan(app, MotorSelection, LineMagnitude, LineStep)
            LM = abs(LineMagnitude);
            LS = abs(LineStep);

            % adjust Line Step if neccessary
            N = round(2*LM/LS);
            LS = 2*LM/N*sign(LineStep);
            app.EditField_line_step.Value = sprintf('%5.3f',LS);

            p = zeros(1,N+1);
            x = -LM:abs(LS):LM;

            if LineStep > 0
                MoveMotorOnce_absolute(app, MotorSelection, -LM);
                ix = 1:length(x);
            elseif LineStep <0
                MoveMotorOnce_absolute(app, MotorSelection, LM);
                ix = length(x):-1:1;
            else
                fprintf('Errror: zero step\n')
                return;
            end

            [sin, tin] = app.Pico.pulse();
            t=(1:length(sin))*tin/1e3;
            plot(app.UIAxes_Pico, t, sin)

            % Time gate
            TG(1) = str2num( app.EditField_timegate_start.Value );
            TG(2) = TG(1) + str2num( app.EditField_timegate_width.Value );
            TGindex = find( t>TG(1) & t<TG(2) );

            app.Pressure( app.PressureIndex, ix(1)) = app.Acou.rms( sin(TGindex) );

            for n = 1:N
                MoveMotorOnce_relative(app, MotorSelection, LS);
                [sin, tin] = app.Pico.pulse();
                plot(app.UIAxes_Pico, t, sin, 'b-', t(TGindex), sin(TGindex), 'r-');

                app.Pressure( app.PressureIndex, ix(n+1) ) = app.Acou.rms( sin(TGindex) );

                if app.PressureIndex ==1
                    plot(app.UIAxesPressure, x, app.Pressure);
                else
                    imagesc(app.UIAxesPressure, app.Pressure);
                end

                if ~app.ScanButton.Value, break, end
            end
        end

        function MoveMotorOnce_relative(app, MotorSelection, Distance)
            app.Motor.move_relative(MotorSelection, Distance);
            switch MotorSelection
                case 1
                    app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 2
                    app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 3
                    app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 4
                    app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
            end
            pause(abs(Distance)/2);
        end

        function MoveMotorOnce_absolute(app, MotorSelection, Location)
            app.Motor.move_absolute(MotorSelection, Location);
            switch MotorSelection
                case 1
                    app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 2
                    app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 3
                    app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
                case 4
                    app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(MotorSelection));
            end
            pause(abs(Location)/2);
        end

        function MoveMotor_RA_PM(app, MotorNumber, Distance, TypeOfMovement, PlusMinus)
            switch TypeOfMovement
                case 'Relative'
                    app.Motor.move_relative(MotorNumber,Distance*PlusMinus);
                case 'Absolute'
                    app.Motor.move_absolute(MotorNumber,Distance);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Acou = Acoustics;
        end

        % Value changed function: ConnectButton_Pico
        function ConnectButton_PicoValueChanged(app, event)
            value = app.ConnectButton_Pico.Value;
            if value
                app.Pico = PicoScope;

                app.Pico.connect();
                app.Lamp_Pico.Color = [0 1 0];
                app.Pico.configure_scope();

                PulseType = app.PulseTypeDropDown.Value;

                RepeatFrequency = str2num(app.PulseRepeatkHzEditField.Value)*1e3;
                CarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;
                NumberCycles = str2num(app.PulseNumberofcyclesEditField.Value);
                Amplitude = str2num(app.PulseAmplitudeVEditField.Value);

                app.Pico.configure_generator(CarrierFrequency, NumberCycles, RepeatFrequency, Amplitude, PulseType);
            else
                app.Pico.disconnect();
                app.Lamp_Pico.Color = [1 0 0];
            end
        end

        % Value changed function: PulseButton
        function PulseButtonValueChanged(app, event)
            [sold, tin] = app.Pico.pulse();
            t=(1:length(sold))*tin/1e3;

            nav = 2^(10*app.SliderAverages.Value);
            ylimit = 2^(10*(app.SliderYaxis.Value-1));
            value = app.PulseButton.Value;

            while value
                [snew, tin] = app.Pico.pulse();
                s = (snew + (nav-1)*sold)/nav;
                mxs = max(abs(s))*ylimit;
                mns = mean(s);

                plot(app.UIAxes_Pico, t, s),
                if mxs>0, ylim(app.UIAxes_Pico,[mns-mxs mns+mxs]); end

                sold = s;

                nav = 2^(10*app.SliderAverages.Value);
                ylimit = 2^(10*(app.SliderYaxis.Value-1));
                value = app.PulseButton.Value;

                drawnow
            end

        end

        % Value changed function: ConnectButton_Motor
        function ConnectButton_MotorValueChanged(app, event)
            value = app.ConnectButton_Motor.Value;
            if value
                app.Motor = MotorXYZT;
                status = app.Motor.connect('COM3');
                if status
                    app.Lamp_Motor.Color = [0 1 0];
                end
                SethomeButtonPushed(app, event);
            else
                app.Motor.disconnect();
                app.Lamp_Motor.Color = [1 0 0];
            end
        end

        % Button pushed function: SethomeButton
        function SethomeButtonPushed(app, event)
            app.Motor.sethome();
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end

        % Button pushed function: XButton_Plus
        function XButton_PlusPushed(app, event)
            motornum = 1;
            linear_movement = str2num(app.EditField_xrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: XButton_Minus
        function XButton_MinusPushed(app, event)
            motornum = 1;
            linear_movement = str2num(app.EditField_xrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Plus
        function YButton_PlusPushed(app, event)
            motornum = 2;
            linear_movement = str2num(app.EditField_yrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: YButton_Minus
        function YButton_MinusPushed(app, event)
            motornum = 2;
            linear_movement = str2num(app.EditField_yrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Plus
        function ZButton_PlusPushed(app, event)
            motornum = 3;
            linear_movement = abs( str2num(app.EditField_zrv.Value) );
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: ZButton_Minus
        function ZButton_MinusPushed(app, event)
            motornum = 3;
            linear_movement = str2num(app.EditField_zrv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Plus
        function TButton_PlusPushed(app, event)
            motornum = 4;
            linear_movement = str2num(app.EditField_trv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,+1);
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: TButton_Minus
        function TButton_MinusPushed(app, event)
            motornum = 4;
            linear_movement = str2num(app.EditField_trv.Value);
            MoveMotor_RA_PM(app,motornum,linear_movement,app.DropDown_MovementType.Value,-1);
            app.EditField_tav.Value = sprintf('%7.1f', app.Motor.curloc(motornum));
        end

        % Button pushed function: GohomeButton
        function GohomeButtonPushed(app, event)
            app.Motor.gohome()
            app.EditField_xav.Value = sprintf('%7.3f', app.Motor.curloc(1));
            app.EditField_yav.Value = sprintf('%7.3f', app.Motor.curloc(2));
            app.EditField_zav.Value = sprintf('%7.3f', app.Motor.curloc(3));
            app.EditField_tav.Value = sprintf('%7.3f', app.Motor.curloc(4));
        end

        % Value changed function: ScanButton
        function ScanButtonValueChanged(app, event)
            value = app.ScanButton.Value;
            if value
                if isempty(app.Pico) || ~isvalid(app.Pico.ps5000aDeviceObj)
                    errordlg('Pico scope is not connected'); return,
                end

                if isempty(app.Motor) || ~isvalid(app.Motor.seport)
                    errordlg('Motor stage is not connected'); return,
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                SethomeButtonPushed(app, event);

                MotorSelection = find(contains(app.LineDropDown_axis_selection.Items,app.LineDropDown_axis_selection.Value));

                %%
                % adjust Line step
                LineMagnitude = str2num(app.EditField_line_range.Value);
                LineStep = str2num(app.EditField_line_step.Value);
                NL = round(2*abs(LineMagnitude)/abs(LineStep));
                LS = 2*abs(LineMagnitude)/NL;
                app.EditField_line_step.Value = sprintf('%5.3f',LS);

                if app.RotateCheckBox.Value

                    RotationMagnitude = str2num(app.EditField_rotation_range.Value);
                    app.EditField_rotation_range.Value = sprintf('%4.1f',abs(RotationMagnitude));

                    RotationStep = str2num(app.EditField_rotation_step.Value);
                    app.EditField_rotation_step.Value = sprintf('%4.1f',abs(RotationStep));

                    GohomeButtonPushed(app, []);

                    %%
                    % adjst rotation step
                    RM = abs(RotationMagnitude);
                    RS = abs(RotationStep);

                    NR = round(2*RotationMagnitude/RotationStep);
                    RS = 2*RM/NR;
                    app.EditField_rotation_step.Value = sprintf('%5.3f',RS);

                    r = -RM:RS:RM;
                    app.Pressure = zeros(NR+1, NL+1);

                    MoveMotorOnce_absolute(app, 4, -RM);

                    linmagn = LineMagnitude;
                    linstep = LineStep;

                    for n = 1:NR+1
                        app.PressureIndex = n;

                        [p, x] = LineScan(app, MotorSelection, linmagn, linstep);
                        linmagn = -linmagn;
                        linstep = -linstep;

                        if ~app.ScanButton.Value, break, end
                        MoveMotorOnce_absolute(app, 4, RS);
                    end
                    app.ScanButton.Value = 0;
                else
                    app.Pressure = zeros(1, NL+1);
                    app.PressureIndex = 1;
                    [p, x] = LineScan(app, MotorSelection, LineMagnitude, LineStep);
                    app.ScanButton.Value = 0;
                end
                GohomeButtonPushed(app, []);

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
        end

        % Value changed function: PulseCarrierFrequencyMHzEditField
        function PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
            PulseType = app.PulseTypeDropDown.Value;

            RepeatFrequency = str2num(app.PulseRepeatkHzEditField.Value)*1e3;
            CarrierFrequency = str2num(app.PulseCarrierFrequencyMHzEditField.Value)*1e6;
            NumberCycles = str2num(app.PulseNumberofcyclesEditField.Value);
            Amplitude = str2num(app.PulseAmplitudeVEditField.Value);
            app.Pico.configure_generator(CarrierFrequency, NumberCycles, RepeatFrequency, Amplitude, PulseType);
            
            AcqTimeNanoSec = str2num(app.PulseAcqTimeEditField.Value)*1e3; % convert from microseconds to nano
            app.Pico.configure_scope_acquisition(AcqTimeNanoSec);
        end

        % Value changed function: PulseNumberofcyclesEditField
        function PulseNumberofcyclesEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseAmplitudeVEditField
        function PulseAmplitudeVEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseRepeatkHzEditField
        function PulseRepeatkHzEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end

        % Value changed function: PulseAcqTimeEditField
        function PulseAcqTimeEditFieldValueChanged(app, event)
            PulseCarrierFrequencyMHzEditFieldValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MotorsTab = uitab(app.TabGroup);
            app.MotorsTab.Title = 'Motors';
            app.XButton_Plus = uibutton(app.MotorsTab, 'push');
            app.XButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @XButton_PlusPushed, true);
            app.XButton_Plus.Tag = 'xp';
            app.XButton_Plus.Text = 'X+';
            app.XButton_Minus = uibutton(app.MotorsTab, 'push');
            app.XButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @XButton_MinusPushed, true);
            app.XButton_Minus.Tag = 'xm';
            app.XButton_Minus.Text = 'X-';
            app.YButton_Plus = uibutton(app.MotorsTab, 'push');
            app.YButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @YButton_PlusPushed, true);
            app.YButton_Plus.Tag = 'yp';
            app.YButton_Plus.Text = 'Y+';
            app.YButton_Minus = uibutton(app.MotorsTab, 'push');
            app.YButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @YButton_MinusPushed, true);
            app.YButton_Minus.Tag = 'ym';
            app.YButton_Minus.Text = 'Y-';
            app.ZButton_Plus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_PlusPushed, true);
            app.ZButton_Plus.Tag = 'zp';
            app.ZButton_Plus.Text = 'Z+';
            app.ZButton_Minus = uibutton(app.MotorsTab, 'push');
            app.ZButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @ZButton_MinusPushed, true);
            app.ZButton_Minus.Tag = 'zm';
            app.ZButton_Minus.Text = 'Z-';
            app.SethomeButton = uibutton(app.MotorsTab, 'push');
            app.SethomeButton.ButtonPushedFcn = createCallbackFcn(app, @SethomeButtonPushed, true);
            app.SethomeButton.Text = 'Set home';
            app.EditField_xrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_xrv.Tag = 'xrv';
            app.EditField_xrv.Value = '1.0';
            app.EditField_yrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_yrv.Tag = 'yrv';
            app.EditField_yrv.Value = '1.0';
            app.EditField_zrv = uieditfield(app.MotorsTab, 'text');
            app.EditField_zrv.Tag = 'zrv';
            app.EditField_zrv.Value = '1.0';
            app.EditField_xav = uieditfield(app.MotorsTab, 'text');
            app.EditField_xav.Tag = 'xav';
            app.EditField_xav.Editable = 'off';
            app.EditField_xav.Enable = 'off';
            app.EditField_xav.Value = '0.0';
            app.EditField_yav = uieditfield(app.MotorsTab, 'text');
            app.EditField_yav.Tag = 'yav';
            app.EditField_yav.Editable = 'off';
            app.EditField_yav.Enable = 'off';
            app.EditField_yav.Value = '0.0';
            app.EditField_zav = uieditfield(app.MotorsTab, 'text');
            app.EditField_zav.Tag = 'zav';
            app.EditField_zav.Editable = 'off';
            app.EditField_zav.Enable = 'off';
            app.EditField_zav.Value = '0.0';
            app.GohomeButton = uibutton(app.MotorsTab, 'push');
            app.GohomeButton.ButtonPushedFcn = createCallbackFcn(app, @GohomeButtonPushed, true);
            app.GohomeButton.Text = 'Go home';
            app.ConnectButton_Motor = uibutton(app.MotorsTab, 'state');
            app.ConnectButton_Motor.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_MotorValueChanged, true);
            app.ConnectButton_Motor.Text = 'Connect';
            app.Lamp_Motor = uilamp(app.MotorsTab);
            app.TButton_Plus = uibutton(app.MotorsTab, 'push');
            app.TButton_Plus.ButtonPushedFcn = createCallbackFcn(app, @TButton_PlusPushed, true);
            app.TButton_Plus.Tag = 'zp';
            app.TButton_Plus.Text = 'T+';
            app.TButton_Minus = uibutton(app.MotorsTab, 'push');
            app.TButton_Minus.ButtonPushedFcn = createCallbackFcn(app, @TButton_MinusPushed, true);
            app.TButton_Minus.Tag = 'zm';
            app.TButton_Minus.Text = 'T-';
            app.EditField_trv = uieditfield(app.MotorsTab, 'text');
            app.EditField_trv.Tag = 'zrv';
            app.EditField_trv.Value = '10.0';
            app.EditField_tav = uieditfield(app.MotorsTab, 'text');
            app.EditField_tav.Tag = 'zav';
            app.EditField_tav.Editable = 'off';
            app.EditField_tav.Enable = 'off';
            app.EditField_tav.Value = '0.0';
            app.DropDown_MovementType = uidropdown(app.MotorsTab);
            app.DropDown_MovementType.Items = {'Relative', 'Absolute', ''};
            app.DropDown_MovementType.Value = 'Relative';
            app.PicoTab = uitab(app.TabGroup);
            app.PicoTab.Title = 'Pico';
            app.UIAxes_Pico = uiaxes(app.PicoTab);
            xlabel(app.UIAxes_Pico, 'Time (\mus)')
            app.PulsePanel = uipanel(app.PicoTab);
            app.PulsePanel.Title = 'Pulse';
            app.CarrierFrequencyMHzEditFieldLabel = uilabel(app.PulsePanel);
            app.CarrierFrequencyMHzEditFieldLabel.Text = 'Carrier Frequency (MHz)';
            app.PulseCarrierFrequencyMHzEditField = uieditfield(app.PulsePanel, 'text');
            app.PulseCarrierFrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseCarrierFrequencyMHzEditFieldValueChanged, true);
            app.PulseCarrierFrequencyMHzEditField.Value = '10.00';
            app.NumberofcyclesEditFieldLabel = uilabel(app.PulsePanel);
            app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
            app.PulseNumberofcyclesEditField = uieditfield(app.PulsePanel, 'text');
            app.PulseNumberofcyclesEditField.ValueChangedFcn = createCallbackFcn(app, @PulseNumberofcyclesEditFieldValueChanged, true);
            app.PulseNumberofcyclesEditField.Value = '10.00';
            app.AmplitudeVEditFieldLabel = uilabel(app.PulsePanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.PulseAmplitudeVEditField = uieditfield(app.PulsePanel, 'text');
            app.PulseAmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAmplitudeVEditFieldValueChanged, true);
            app.PulseAmplitudeVEditField.Value = '0.1';
            app.TypeDropDownLabel = uilabel(app.PulsePanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.PulseTypeDropDown = uidropdown(app.PulsePanel);
            app.PulseTypeDropDown.Items = {'Sinusoidal', 'Gaussian', 'Chirp'};
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Value = 'Sinusoidal';
            app.PulseButton = uibutton(app.PicoTab, 'state');
            app.PulseButton.ValueChangedFcn = createCallbackFcn(app, @PulseButtonValueChanged, true);
            app.PulseButton.Text = 'Pulse';
            app.ConnectButton_Pico = uibutton(app.PicoTab, 'state');
            app.ConnectButton_Pico.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_PicoValueChanged, true);
            app.ConnectButton_Pico.Text = 'Connect';
            app.Lamp_Pico = uilamp(app.PicoTab);
            app.SliderAverages = uislider(app.PicoTab);
            app.SliderAverages.Limits = [0 1];
            app.SliderAverages.MajorTicks = [];
            app.SliderAverages.MinorTicks = [];
            app.SliderYaxis = uislider(app.PicoTab);
            app.SliderYaxis.Limits = [0 1];
            app.SliderYaxis.MajorTicks = [];
            app.SliderYaxis.MinorTicks = [];
            app.SliderYaxis.Value = 1;
            app.RepeatkHzEditFieldLabel = uilabel(app.PicoTab);
            app.RepeatkHzEditFieldLabel.Text = 'Repeat (kHz)';
            app.PulseRepeatkHzEditField = uieditfield(app.PicoTab, 'text');
            app.PulseRepeatkHzEditField.ValueChangedFcn = createCallbackFcn(app, @PulseRepeatkHzEditFieldValueChanged, true);
            app.PulseRepeatkHzEditField.Value = '100.0';
            app.AcqtimeusLabel = uilabel(app.PicoTab);
            app.AcqtimeusLabel.Text = 'Acq time (us)';
            app.PulseAcqTimeEditField = uieditfield(app.PicoTab, 'text');
            app.PulseAcqTimeEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAcqTimeEditFieldValueChanged, true);
            app.PulseAcqTimeEditField.Value = '20.0';
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.UIAxesPressure = uiaxes(app.ScanTab);
            xlabel(app.UIAxesPressure, 'Z (mm)')
            ylabel(app.UIAxesPressure, 'Pressure (Pa)')
            zlabel(app.UIAxesPressure, 'Z')
            app.LineDropDown_axis_selection = uidropdown(app.ScanTab);
            app.LineDropDown_axis_selection.Items = {'X', 'Y', 'Z'};
            app.LineDropDown_axis_selection.Tooltip = {'Axes to scan'};
            app.LineDropDown_axis_selection.Value = 'X';
            app.EditField_line_range = uieditfield(app.ScanTab, 'text');
            app.EditField_line_range.Tag = 'xrv';
            app.EditField_line_range.Tooltip = {'Linear scan plus minus range in mm.'};
            app.EditField_line_range.Value = '5.0';
            app.RotateCheckBox = uicheckbox(app.ScanTab);
            app.RotateCheckBox.Text = 'Rotate';
            app.EditField_rotation_range = uieditfield(app.ScanTab, 'text');
            app.EditField_rotation_range.Tag = 'xrv';
            app.EditField_rotation_range.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_rotation_range.Value = '90';
            app.EditField_line_step = uieditfield(app.ScanTab, 'text');
            app.EditField_line_step.Tag = 'xrv';
            app.EditField_line_step.Tooltip = {'Linear step in mm.'};
            app.EditField_line_step.Value = '1.0';
            app.EditField_rotation_step = uieditfield(app.ScanTab, 'text');
            app.EditField_rotation_step.Tag = 'xrv';
            app.EditField_rotation_step.Tooltip = {'Rotational step'};
            app.EditField_rotation_step.Value = '10';
            app.ScanButton = uibutton(app.ScanTab, 'state');
            app.ScanButton.ValueChangedFcn = createCallbackFcn(app, @ScanButtonValueChanged, true);
            app.ScanButton.Text = 'Scan';
            app.EditField_timegate_start = uieditfield(app.ScanTab, 'text');
            app.EditField_timegate_start.Tag = 'xrv';
            app.EditField_timegate_start.Tooltip = {'Rotational plus minus range in degrees'};
            app.EditField_timegate_start.Value = '1';
            app.EditField_timegate_width = uieditfield(app.ScanTab, 'text');
            app.EditField_timegate_width.Tag = 'xrv';
            app.EditField_timegate_width.Tooltip = {'Rotational step'};
            app.EditField_timegate_width.Value = '2';
            app.TimegatestartusLabel = uilabel(app.ScanTab);
            app.TimegatestartusLabel.Text = 'Time gate start (us)';
            app.widthusLabel = uilabel(app.ScanTab);
            app.widthusLabel.Text = 'width (us)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HydroScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end